classdef metricPlix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MetricPlixUIFigure      matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        SupportMenu             matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        Table                   matlab.ui.container.Tab
        SaveButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        RowNameEditField        matlab.ui.control.EditField
        RowNameLabel            matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        GraphTab                matlab.ui.container.Tab
        PreviewPanel            matlab.ui.container.Panel
        TasksPanel              matlab.ui.container.Panel
        RowDataList             matlab.ui.control.ListBox
        AlgorithmsPanel         matlab.ui.container.Panel
        ColumnDataList          matlab.ui.control.ListBox
        ExperimentSettingPanel  matlab.ui.container.Panel
        StatusLabel             matlab.ui.control.Label
        LampLabel               matlab.ui.control.Label
        StatusLamp              matlab.ui.control.Lamp
        RunnumberSpinner        matlab.ui.control.Spinner
        Label                   matlab.ui.control.Label
        MetricDropDown          matlab.ui.control.DropDown
        MetricDropDownLabel     matlab.ui.control.Label
        SelectdatafolderPanel   matlab.ui.container.Panel
        LoadButton              matlab.ui.control.Button
        SelectButton            matlab.ui.control.Button
        ResultfolderEditField   matlab.ui.control.EditField
        ContextMenu             matlab.ui.container.ContextMenu
        DeleteMenu              matlab.ui.container.Menu
        MoveUpMenu              matlab.ui.container.Menu
        MoveDownMenu            matlab.ui.container.Menu
        ContextMenu2            matlab.ui.container.ContextMenu
        DeleteMenu_2            matlab.ui.container.Menu
        MoveUpMenu_2            matlab.ui.container.Menu
        MoveDownMenu_2          matlab.ui.container.Menu
        ContextMenu3            matlab.ui.container.ContextMenu
        EdittitleMenu           matlab.ui.container.Menu
        EditXaxislabelMenu      matlab.ui.container.Menu
        EditYaxislabelMenu      matlab.ui.container.Menu
        EditZaxislabelMenu      matlab.ui.container.Menu
        SetfontsizeMenu         matlab.ui.container.Menu
    end

    properties (Access = public)
        workData;
        % - baseDir
        % - confPath
        % - columnData(algorithm)
        % - rowData(problem/dataset)
        % - metric
        % - runNumber
        % - rowName
    end

    properties (Access = private)
        appPath;               % App running directory
    end
    
    methods (Access = private)
                
        function columnData = loadColumnData(app)
            %% loadColumnData - load column(algorithm) data
            % Inputs:
            % 
            % Outputs:
            % - data: columnData
            
            dirInfo = dir(app.workData.baseDir);
            dirInfo = dirInfo([dirInfo.isdir] & ~ismember({dirInfo.name}, {'.', '..'}));
            
            columnData = arrayfun(@(s) s.name, dirInfo, 'UniformOutput', false);
            
        end

        function rowData = loadRowData(app)
            %% loadRowData - load row(problem/dataset) data
            % Inputs:
            % 
            % Outputs:
            % - data: rowData
            rowData = {};
            for i = 1:numel(app.ColumnDataList.Items)
                dirPath = fullfile(app.workData.baseDir, app.ColumnDataList.Items{i});
                dirInfo = dir(dirPath);
                dirInfo = dirInfo([dirInfo.isdir] & ~ismember({dirInfo.name}, {'.', '..'}));
                curRowData = arrayfun(@(s) s.name, dirInfo, 'UniformOutput', false);
                if isempty(rowData)
                    rowData = curRowData;
                else
                    rowData = intersect(rowData, curRowData);
                end
            end
        end
        
        function showInTable(app)
            %% Create table column
            app.UITable.ColumnName = app.workData.columnData;
            app.UITable.RowName = [app.workData.rowData, "+/=/-", "Rank"];
            app.UITable.Data = [];
            %% Load data
            [str, best, rankBest] = app.loadData();
            app.UITable.Data = str;

            %% Highlight the best
            boldStyle = uistyle('FontWeight', 'bold');
            removeStyle(app.UITable);
            cellIndices = [(1:length(best))', best];
            addStyle(app.UITable, boldStyle, "cell", cellIndices);
            % - highlight the rank
            if ~isempty(rankBest)
                addStyle(app.UITable, boldStyle, 'cell', [length(best)+2, rankBest]);
            end

        end
        
        function [str, best, rankBest] = loadData(app)
            NC = numel(app.workData.columnData);
            NR = numel(app.workData.rowData);
            str = cell(NR+2, NC);
            mData = zeros(NR, NC);
            sData = zeros(NR, NC);
            refMData = zeros(NR, NC);
            best = zeros(NR, 1);
            wilResult = cell(NR, NC);
            doStatTest = NC > 1;

            % Load metric
            metricName = [app.workData.metric, 'Metric'];
            metricPath = fullfile(app.appPath, 'metrics', app.workData.metric, [metricName, '.m']);
            addpath(fileparts(metricPath))

            % Load data and conduct statistical test
            for i = 1:NR
                realValue = zeros(NC, app.workData.runNumber);
                refValue = zeros(NC, app.workData.runNumber);
                for j = 1:NC
                    path = fullfile( ...
                        app.workData.baseDir, ...
                        app.workData.columnData{j}, ...
                        app.workData.rowData{i});
                    
                    for k = 1:app.workData.runNumber
                        data = load(fullfile(path, num2str(k)));
                        [realValue(j, k), refValue(j, k)] = feval(metricName, data, app.workData.rowData{i});
                    end
                    mData(i, j) = mean(realValue(j, :));
                    sData(i, j) = std(realValue(j, :));
                    refMData(i, j) = mean(refValue(j, :));
                    str{i, j} = sprintf('%.4f±%.4f', mData(i, j), sData(i, j));
                end
                [~, best(i)] = min(refMData(i, :));
                
                if ~doStatTest
                    continue
                end
                % Calculate the statistical test results
                diff = false(1, NC-1);
                for j = 1:NC-1
                    diff(j) = ranksum(refValue(j, :), refValue(end, :)) < 0.05;
                    if ~diff(j) || refMData(i, j) == refMData(i, end)
                        wilResult{i, j} = '=';
                    elseif refMData(i, j) > refMData(i, end)
                        wilResult{i, j} = '+';
                    else
                        wilResult{i, j} = '-';
                    end
                    str{i, j} = [str{i, j} wilResult{i, j}];
                end
            end
            rankBest = [];
            if doStatTest
                % Count the statistical test results
                % - Count win/loss/tie
                win = cellfun(@(s)~isempty(s) && strcmp('+', s(end)), wilResult(1:end, 1:end-1));
                loss = cellfun(@(s)~isempty(s) && strcmp('-', s(end)), wilResult(1:end, 1:end-1));
                tie = cellfun(@(s)~isempty(s) && strcmp('=', s(end)), wilResult(1:end, 1:end-1));
                for j = 1:NC-1
                    str{end-1, j} = sprintf('%d/%d/%d', sum(win(:, j), 1), sum(tie(:, j), 1), sum(loss(:, j), 1));
                end
                
                % - Friedman mean rank
                if size(refMData, 1) >= 2
                    [~, ~, rank] = friedman(refMData, 1, 'off');
                    rank = rank.meanranks;
                    [~, rankBest] = min(rank);
                    str(end, :) = arrayfun(@(s) sprintf('%.2f', s), rank, 'UniformOutput', false);
                end
                
            end

        end
        
        function saveTable(app)
            if ~isempty(app.UITable.Data)
                try
                    [name, path] = uiputfile( ...
                        {'*.tex', 'TeX table'}, ...
                        '', ...      % dialog title
                        'new' ...    % filename
                        );
                    if ischar(name)
                        [~, ~, type] = fileparts(name);
                        switch type
                            case '.tex'
                                app.table2tex(fullfile(path, name));
                        end
                    end
                catch err
                    rethrow(err);
                end
            end
        end

        function table2tex(app, savepath)
            % Convert the data
            data = [app.RowNameEditField.Value, app.UITable.ColumnName'; app.UITable.RowName, app.UITable.Data];
            [nR, nC] = size(data);
            nP = nR - 3;
            nA = nC - 1;
            styleLoc = cat(1, app.UITable.StyleConfigurations.TargetIndex{:});
            mainData = data(2:nP+3, end-nA+1:end);
            mainData = regexprep(mainData,'+$', '($+$)');
            mainData = regexprep(mainData,'-$', '($-$)');
            mainData = regexprep(mainData,'=$', '($\\approx$)');
            mainData = regexprep(mainData, '±', '$\\pm$');
            for i = 1 : size(styleLoc,1)
                mainData{styleLoc(i,1), styleLoc(i,2)-(size(data,2)-nA-1)} = ['\textbf{',mainData{styleLoc(i,1), styleLoc(i,2)-(size(data,2)-nA-1)},'}'];
            end
            data(2:nP+3,end-nA+1:end) = mainData;
            data(end-1,1)          = regexprep(data(end-1,1),'^\+/=/\-$',['\\multicolumn{',num2str(size(data,2)-nA),'}{c}{$+$/$\\approx$/$-$}']);
            data(1,2:end-nA)       = strcat('$',data(1,2:end-nA),'$');
            data(:, 1)             = regexprep(data(:, 1), '_', '\\_');
            data(1,end-nA+1:end)   = regexprep(data(1,end-nA+1:end),'_','\\_');

            title = ['The ' regexprep(app.MetricDropDown.Value, '_', '\\_') ' obtained by ' data{1, end} ' and comparison algorithms'];
            data(1, :) = cellfun(@(s) ['\textbf{' s '}'], data(1, :), 'UniformOutput', false);
            noEmpty = ~cellfun(@isempty,data(:,1));
            for i = 2 : nP+1
                if noEmpty(i)
                    data{i,1} = sprintf('\\multirow{%d}{*}{%s}',find([noEmpty(i+1:end);true],1),data{i,1});
                end
            end
            % Generate the TeX code
            code = eval(sprintf('strcat(%s)',strjoin(arrayfun(@(S)num2str(S,'data(:,%d)'),1:size(data,2),'UniformOutput',false),',''&'',')));
            code = strcat(code,'\\');
            if ~isempty(regexp(code{end,1},'^\\multicolumn','once'))
                temp = strfind(code{end,1},'&');
                code{end,1}(temp(1:size(data,2)-nA-1)) = [];
            end
            noEmpty = find(noEmpty);
            for i = 3 : length(noEmpty)
                code = [code(1:noEmpty(i)+i-4);'\hline';code(noEmpty(i)+i-3:end)];
            end
            code = ['\documentclass[journal]{IEEEtran}'
                    '\usepackage{multirow,booktabs,color,soul,threeparttable}'
                    '\definecolor{hl}{rgb}{0.75,0.75,0.75}'
                    '\sethlcolor{hl}'
                    '\begin{document}'
                    '\begin{table*}[htbp]'
                    '\renewcommand{\arraystretch}{1.2}'
                    '\setlength\tabcolsep{2pt}'
                    '\centering'
                    ['\caption{' title '}']
                    ['\begin{tabular}{',repmat('c',1,size(data,2)),'}']
                    '\toprule'
                    code(1)
                    '\midrule'
                    code(2:end)
                    '\bottomrule'
                    '\end{tabular}'
                    '\label{No Label}'
                    '\end{table*}'
                    '\end{document}'];
            fid = fopen(savepath, 'wt');
            for i = 1 : length(code)
                fprintf(fid,'%s\n',code{i});
            end
            fclose(fid);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Placeholder for the upcoming graph view.
            graphLayout = uigridlayout(app.GraphTab, [1 1]);
            graphLayout.ColumnWidth = {'1x'};
            graphLayout.RowHeight = {'1x'};
            graphLayout.BackgroundColor = app.GraphTab.BackgroundColor;
            uilabel(graphLayout, 'Text', 'Coming Soon~', ...
                'HorizontalAlignment', 'center', 'VerticalAlignment', 'center', ...
                'FontSize', 16, 'FontWeight', 'bold', 'FontColor', [1 0.2 0.3]);

            %% Initialization
            % Load metrics
            app.appPath = fileparts(mfilename('fullpath'));
            metricsDir = fullfile(app.appPath, 'metrics');
            metricsDirInfo = dir(metricsDir);
            metricsDirInfo = metricsDirInfo([metricsDirInfo.isdir] & ~ismember({metricsDirInfo.name}, {'.', '..'}));
            
            for i = 1 : length(metricsDirInfo)
                app.MetricDropDown.Items{end + 1} = metricsDirInfo(i).name;
            end

            if ~isempty(metricsDirInfo)
                app.MetricDropDown.Value = app.MetricDropDown.Items{1};
            end

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            %% Select result folder
            resultFolder = uigetdir(pwd, 'Select result folder');
            if resultFolder ~= 0
                app.ResultfolderEditField.Value = resultFolder;
            end
            % Clear current list
            app.RowDataList.Items = {};
            app.ColumnDataList.Items = {};

            if ~isempty(app.ResultfolderEditField.Value) ...
                    && exist(app.ResultfolderEditField.Value, 'dir')
                app.workData.baseDir = app.ResultfolderEditField.Value;
                % Load column(algorithm) data
                columnData = app.loadColumnData();
                app.ColumnDataList.Items = columnData;
            end

            app.SaveButton.Enable = false;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Load row(problem/dataset) data
            if ~isempty(app.ColumnDataList.Items)
                rowData = app.loadRowData();
                app.RowDataList.Items = rowData;
            end

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% Save columnData and rowData in workData
            if isempty(app.ColumnDataList.Items) || isempty(app.RowDataList.Items)
                errordlg('Column and row data can not be empty!', 'Error')
                return
            end
            app.SaveButton.Enable = false;
            app.StartButton.Enable = false;
            app.StatusLamp.Color = [1, 0, 0];
            app.LampLabel.Text = 'Busy';
            drawnow
            try
                app.workData.columnData = app.ColumnDataList.Items;
                app.workData.rowData = app.RowDataList.Items;
    
                %% Experimental Setting
                app.workData.metric = app.MetricDropDown.Value;
                app.workData.runNumber = app.RunnumberSpinner.Value;
    
                %% Show result in table
                app.showInTable();
            catch err
                errordlg(err.message, 'Error');
            end
            %% Enable save button
            app.SaveButton.Enable = true;
            app.StartButton.Enable = true;
            app.StatusLamp.Color = [0, 1, 0];
            app.LampLabel.Text = 'Free';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.RowNameEditField.Value)
                errordlg('Row name can not be empty!', 'Error');
                return;
            end
            app.workData.rowName = app.RowNameEditField.Value;
            
            app.saveTable();
        end

        % Menu selected function: DeleteMenu
        function ColumnDeleteMenuSelected(app, event)
            if ~isempty(app.ColumnDataList.Value)
                deletedItem = app.ColumnDataList.Value;
                app.ColumnDataList.Items = app.ColumnDataList.Items(~ismember(app.ColumnDataList.Items, deletedItem));
                app.workData.columnData = app.ColumnDataList.Items;
            end
        end

        % Menu selected function: MoveUpMenu
        function ColumnDataMoveUpMenuSelected(app, event)
            if ~isempty(app.ColumnDataList.Value)
                selectedValue = app.ColumnDataList.Value;
                selectedIdx = find(ismember(app.ColumnDataList.Items, selectedValue));
                if selectedIdx > 1
                    tmp = app.ColumnDataList.Items{selectedIdx-1};
                    app.ColumnDataList.Items{selectedIdx-1} = app.ColumnDataList.Items{selectedIdx};
                    app.ColumnDataList.Items{selectedIdx} = tmp;
                    app.workData.columnData = app.ColumnDataList.Items;
                end
            end
        end

        % Menu selected function: MoveDownMenu
        function ColumnDataMoveDownMenuSelected(app, event)
            if ~isempty(app.ColumnDataList.Value)
                selectedValue = app.ColumnDataList.Value;
                selectedIdx = find(ismember(app.ColumnDataList.Items, selectedValue));
                if selectedIdx < numel(app.ColumnDataList.Items)
                    tmp = app.ColumnDataList.Items{selectedIdx+1};
                    app.ColumnDataList.Items{selectedIdx+1} = app.ColumnDataList.Items{selectedIdx};
                    app.ColumnDataList.Items{selectedIdx} = tmp;
                    app.workData.columnData = app.ColumnDataList.Items;
                end
            end
        end

        % Menu selected function: DeleteMenu_2
        function RowDataDeleteMenuSelected(app, event)
            if ~isempty(app.RowDataList.Value)
                deletedItem = app.RowDataList.Value;
                app.RowDataList.Items = app.RowDataList.Items(~ismember(app.RowDataList.Items, deletedItem));
                app.workData.rowData = app.RowDataList.Items;
            end
        end

        % Menu selected function: MoveUpMenu_2
        function RowDataMoveUpMenuSelected(app, event)
            if ~isempty(app.RowDataList.Value)
                selectedValue = app.RowDataList.Value;
                selectedIdx = find(ismember(app.RowDataList.Items, selectedValue));
                if selectedIdx > 1
                    tmp = app.RowDataList.Items{selectedIdx-1};
                    app.RowDataList.Items{selectedIdx-1} = app.RowDataList.Items{selectedIdx};
                    app.RowDataList.Items{selectedIdx} = tmp;
                    app.workData.rowData = app.RowDataList.Items;
                end
            end
        end

        % Menu selected function: MoveDownMenu_2
        function RowDataMoveDownMenuSelected(app, event)
            if ~isempty(app.RowDataList.Value)
                selectedValue = app.RowDataList.Value;
                selectedIdx = find(ismember(app.RowDataList.Items, selectedValue));
                if selectedIdx < numel(app.RowDataList.Items)
                    tmp = app.RowDataList.Items{selectedIdx+1};
                    app.RowDataList.Items{selectedIdx+1} = app.RowDataList.Items{selectedIdx};
                    app.RowDataList.Items{selectedIdx} = tmp;
                    app.workData.rowData = app.RowDataList.Items;
                end
            end
        end

        % Menu selected function: SaveMenu
        function ConfigSaveMenuSelected(app, event)
            %% Save config
            if ~isfield(app.workData, 'confPath') || isempty(app.workData.confPath)
                [name, path] = uiputfile( ...
                    {'*.mat', 'Config file'}, ...
                    '', ...                % dialog title
                    'conf' ....            % filename
                );
                if ~ischar(name)
                    return
                end
                app.workData.confPath = fullfile(path, name);
            end
            % Save
            conf = app.workData;
            save(app.workData.confPath, 'conf');
        end

        % Menu selected function: OpenMenu
        function ConfigOpenMenuSelected(app, event)
            %% Open config
            [name, path] = uigetfile( ...
                {'*.mat', 'Config file'}, ...
                '', ...                % dialog title
                'conf' ....            % filename
            );
            if ~ischar(name)
                return
            end
            
            confPath = fullfile(path, name);
            load(confPath);
            app.workData = conf;
            app.workData.confPath = confPath;
            app.SaveButton.Enable = false;

            % Load data
            if isfield(app.workData, 'baseDir')
                app.ResultfolderEditField.Value = app.workData.baseDir;
            else
                app.workData.baseDir = '';
            end
            if isfield(app.workData, 'metric') && ~isempty(app.workData.metric)
                app.MetricDropDown.Value = app.workData.metric;
            end
            if isfield(app.workData, 'runNumber') && ~isempty(app.workData.runNumber)
                app.RunnumberSpinner.Value = app.workData.runNumber;
            end
            if isfield(app.workData, 'rowName') && ~isempty(app.workData.rowName)
                app.RowNameEditField.Value = app.workData.rowName;
            end
            if isfield(app.workData, 'columnData')
                app.ColumnDataList.Items = app.workData.columnData;
            end
            if isfield(app.workData, 'rowData')
                app.RowDataList.Items = app.workData.rowData;
            end
            if ~isempty(app.workData.baseDir) && numel(app.ColumnDataList.Items) == 0
                columnData = app.loadColumnData();
                app.ColumnDataList.Items = columnData;
            end
        end

        % Value changed function: RowNameEditField
        function RowNameEditFieldValueChanged(app, event)
            value = app.RowNameEditField.Value;
            if ~isempty(value)
                app.workData.rowName = value;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            pos = app.MetricPlixUIFigure.Position;
            figWidth = 300;
            figHeight = 240;
            figLeft = pos(1) + (pos(3) - figWidth) / 2;
            figBottom = pos(2) + (pos(4) - figHeight) / 2;
            figPos = [figLeft, figBottom, figWidth, figHeight];

            f = uifigure('Name', 'About', 'Position', figPos, 'Color', 'w', 'WindowStyle', 'modal', 'Resize', 'off');
            logoPath = fullfile(app.appPath, 'resources', 'metricplix-logo.jpg');
            uiimage(f, 'Position', [20 176 64 64], 'ImageSource', logoPath, 'ScaleMethod', 'fit');
            uilabel(f, 'Position', [92 200 180 30], 'Text', 'MetricPlix', 'HorizontalAlignment', 'left', 'FontSize', 18);
            uilabel(f, 'Position', [92 178 180 22], 'Text', {'Metrics. Plots. Mix.'}, 'HorizontalAlignment', 'left', 'FontSize', 12, 'FontColor', [.4 .4 .4], 'FontAngle', 'italic');
            uilabel(f, 'Position', [30 130 240 50], 'Text', {'* Preset and Custom Metrics and Graphics', '* Statistical Test', '* Preservation of Result'}, 'HorizontalAlignment', 'left', 'FontSize', 12);
            uilabel(f, 'Position', [30 90 150 40], 'Text', {'Copyright (c) 2026', 'Fanrong Kong'}, 'HorizontalAlignment', 'left', 'FontSize', 12);
            uilabel(f, 'Position', [30 20 180 80], 'Text', {...
                '<a href="mailto:kevin@fanrenkong.com">kevin@fanrenkong.com</a>', ...
                '<a href="https://www.fanrenkong.com">https://www.fanrenkong.com</a>', ...
                '<a href="https://github.com/fanrenkong/MetricPlix">https://github.com/fanrenkong/MetricPlix</a>'}, ...
                'HorizontalAlignment', 'left', 'FontSize', 12, 'Interpreter', 'html');
            uibutton(f, 'Position', [210 10 70 22], 'Text', 'OK', 'ButtonpushedFcn', @(~, ~)delete(f));
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab.Title == "Graphics"
                plot(app.UIAxes, [1,2,3,4], [0.3, 0.25, 0.77, 0.6]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MetricPlixUIFigure = uifigure('Visible', 'off');
            app.MetricPlixUIFigure.Name = 'MetricPlix';
            app.FileMenu = uimenu(app.MetricPlixUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigOpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigSaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SupportMenu = uimenu(app.MetricPlixUIFigure);
            app.SupportMenu.Text = 'Support';
            app.AboutMenu = uimenu(app.SupportMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.MetricPlixUIFigure);
            app.SelectdatafolderPanel = uipanel(app.GridLayout);
            app.SelectdatafolderPanel.Title = 'Select data folder:';
            app.ResultfolderEditField = uieditfield(app.SelectdatafolderPanel, 'text');
            app.ResultfolderEditField.Editable = 'off';
            app.ResultfolderEditField.Placeholder = 'Please select folder';
            app.SelectButton = uibutton(app.SelectdatafolderPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LoadButton = uibutton(app.SelectdatafolderPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExperimentSettingPanel = uipanel(app.GridLayout);
            app.ExperimentSettingPanel.Title = 'Experiment Setting';
            app.MetricDropDownLabel = uilabel(app.ExperimentSettingPanel);
            app.MetricDropDownLabel.Text = 'Metric:';
            app.MetricDropDown = uidropdown(app.ExperimentSettingPanel);
            app.MetricDropDown.Items = {};
            app.MetricDropDown.Value = {};
            app.Label = uilabel(app.ExperimentSettingPanel);
            app.Label.Text = 'Run number:';
            app.RunnumberSpinner = uispinner(app.ExperimentSettingPanel);
            app.RunnumberSpinner.Value = 30;
            app.StatusLamp = uilamp(app.ExperimentSettingPanel);
            app.LampLabel = uilabel(app.ExperimentSettingPanel);
            app.LampLabel.Text = 'Free';
            app.StatusLabel = uilabel(app.ExperimentSettingPanel);
            app.StatusLabel.Text = 'Status:';
            app.PreviewPanel = uipanel(app.GridLayout);
            app.PreviewPanel.Title = 'Preview';
            app.AlgorithmsPanel = uipanel(app.PreviewPanel);
            app.AlgorithmsPanel.Title = 'Algorithms';
            app.ColumnDataList = uilistbox(app.AlgorithmsPanel);
            app.ColumnDataList.Items = {};
            app.ColumnDataList.Multiselect = 'on';
            app.ColumnDataList.Value = {};
            app.TasksPanel = uipanel(app.PreviewPanel);
            app.TasksPanel.Title = 'Tasks';
            app.RowDataList = uilistbox(app.TasksPanel);
            app.RowDataList.Items = {};
            app.RowDataList.Value = {};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.RowNameLabel = uilabel(app.Table);
            app.RowNameLabel.Text = 'Row Name:';
            app.RowNameEditField = uieditfield(app.Table, 'text');
            app.RowNameEditField.ValueChangedFcn = createCallbackFcn(app, @RowNameEditFieldValueChanged, true);
            app.RowNameEditField.Value = 'Dataset';
            app.StartButton = uibutton(app.Table, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.Table, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.ContextMenu = uicontextmenu(app.MetricPlixUIFigure);
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @ColumnDeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.MoveUpMenu = uimenu(app.ContextMenu);
            app.MoveUpMenu.MenuSelectedFcn = createCallbackFcn(app, @ColumnDataMoveUpMenuSelected, true);
            app.MoveUpMenu.Text = 'Move Up';
            app.MoveDownMenu = uimenu(app.ContextMenu);
            app.MoveDownMenu.MenuSelectedFcn = createCallbackFcn(app, @ColumnDataMoveDownMenuSelected, true);
            app.MoveDownMenu.Text = 'Move Down';
            % Assign app.ContextMenu
            app.ColumnDataList.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.MetricPlixUIFigure);
            app.DeleteMenu_2 = uimenu(app.ContextMenu2);
            app.DeleteMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RowDataDeleteMenuSelected, true);
            app.DeleteMenu_2.Text = 'Delete';
            app.MoveUpMenu_2 = uimenu(app.ContextMenu2);
            app.MoveUpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RowDataMoveUpMenuSelected, true);
            app.MoveUpMenu_2.Text = 'Move Up';
            app.MoveDownMenu_2 = uimenu(app.ContextMenu2);
            app.MoveDownMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RowDataMoveDownMenuSelected, true);
            app.MoveDownMenu_2.Text = 'Move Down';
            % Assign app.ContextMenu2
            app.RowDataList.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.MetricPlixUIFigure);
            app.EdittitleMenu = uimenu(app.ContextMenu3);
            app.EdittitleMenu.Text = 'Edit title';
            app.EditXaxislabelMenu = uimenu(app.ContextMenu3);
            app.EditXaxislabelMenu.Text = 'Edit X axis label';
            app.EditYaxislabelMenu = uimenu(app.ContextMenu3);
            app.EditYaxislabelMenu.Text = 'Edit Y axis label';
            app.EditZaxislabelMenu = uimenu(app.ContextMenu3);
            app.EditZaxislabelMenu.Text = 'Edit Z axis label';
            app.SetfontsizeMenu = uimenu(app.ContextMenu3);
            app.SetfontsizeMenu.Text = 'Set font size';
            % Show the figure after all components are created
            app.MetricPlixUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metricPlix

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MetricPlixUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MetricPlixUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MetricPlixUIFigure)
        end
    end
end